classdef Interface_manip_matlab2018 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BasicTab                        matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ImagesPanel                     matlab.ui.container.Panel
        AverageandStandarddeviationFolderPathEditField  matlab.ui.control.EditField
        AverageandStandarddeviationFolderPathEditFieldLabel  matlab.ui.control.Label
        SELECTFOLDERButton_2            matlab.ui.control.Button
        FilenameStandarddeviationEditField  matlab.ui.control.EditField
        FilenameStandarddeviationLabel  matlab.ui.control.Label
        FilenameAverageEditField        matlab.ui.control.EditField
        FilenameAverageLabel            matlab.ui.control.Label
        SAVETHEIMAGESButton             matlab.ui.control.Button
        LIVESwitch                      matlab.ui.control.Switch
        LIVESwitchLabel                 matlab.ui.control.Label
        StandardDev                     matlab.ui.control.UIAxes
        Average                         matlab.ui.control.UIAxes
        ConnectionsPanel                matlab.ui.container.Panel
        ConnectButtonMotor              matlab.ui.control.Button
        ConnectButtonSLM                matlab.ui.control.Button
        DisconnectButtonCam             matlab.ui.control.Button
        ConnectButtonCam                matlab.ui.control.Button
        MotorstageLamp                  matlab.ui.control.Lamp
        MotorstageLampLabel             matlab.ui.control.Label
        SLMLamp                         matlab.ui.control.Lamp
        SLMLampLabel                    matlab.ui.control.Label
        CameraLamp                      matlab.ui.control.Lamp
        CameraLampLabel                 matlab.ui.control.Label
        SavingPanel                     matlab.ui.container.Panel
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        SAVEACQUISITIONCheckBox         matlab.ui.control.CheckBox
        FolderPathEditField             matlab.ui.control.EditField
        FolderPathEditFieldLabel        matlab.ui.control.Label
        SELECTFOLDERButton              matlab.ui.control.Button
        ImagingParametersPanel          matlab.ui.container.Panel
        StepmmEditField                 matlab.ui.control.NumericEditField
        StepmmEditFieldLabel            matlab.ui.control.Label
        ResultPosition                  matlab.ui.control.NumericEditField
        GetpositionMotorstageButton     matlab.ui.control.Button
        MovethezstagepositionLabel      matlab.ui.control.Label
        DOWNButton                      matlab.ui.control.Button
        UPButton                        matlab.ui.control.Button
        NumberofpixelsLabel             matlab.ui.control.Label
        forxEditField                   matlab.ui.control.NumericEditField
        forxEditFieldLabel              matlab.ui.control.Label
        foryEditField                   matlab.ui.control.NumericEditField
        foryEditFieldLabel              matlab.ui.control.Label
        CameraformatDropDown            matlab.ui.control.DropDown
        CameraformatDropDownLabel       matlab.ui.control.Label
        NumberofspecklesEditField       matlab.ui.control.NumericEditField
        NumberofspecklesEditFieldLabel  matlab.ui.control.Label
        STARTButton                     matlab.ui.control.Button
        AcquisitiontimeEditField        matlab.ui.control.NumericEditField
        AcquisitiontimeEditFieldLabel   matlab.ui.control.Label
        ZSTACKTab                       matlab.ui.container.Tab
        ImagesPanel_2                   matlab.ui.container.Panel
        SAVEAVERAGESANDSTANDARDSDEVIATIONSCheckBox  matlab.ui.control.CheckBox
        AveragesandStandardsdeviationsFolderPathEditField  matlab.ui.control.EditField
        AveragesandStandardsdeviationsFolderPathEditFieldLabel  matlab.ui.control.Label
        SELECTFOLDERButton_4            matlab.ui.control.Button
        FilenameStandarddeviationEditField_2  matlab.ui.control.EditField
        FilenameStandarddeviationEditField_2Label  matlab.ui.control.Label
        FilenameAverageEditField_2      matlab.ui.control.EditField
        FilenameAverageEditField_2Label  matlab.ui.control.Label
        StandardDev_2                   matlab.ui.control.UIAxes
        Average_2                       matlab.ui.control.UIAxes
        ConnectionsPanel_2              matlab.ui.container.Panel
        ConnectButtonMotor_2            matlab.ui.control.Button
        ConnectButtonSLM_2              matlab.ui.control.Button
        DisconnectButtonCam_2           matlab.ui.control.Button
        ConnectButtonCam_2              matlab.ui.control.Button
        MotorstageLamp_2                matlab.ui.control.Lamp
        MotorstageLamp_2Label           matlab.ui.control.Label
        SLMLamp_2                       matlab.ui.control.Lamp
        SLMLamp_2Label                  matlab.ui.control.Label
        CameraLamp_2                    matlab.ui.control.Lamp
        CameraLamp_2Label               matlab.ui.control.Label
        SavingPanel_2                   matlab.ui.container.Panel
        FilenameEditField_2             matlab.ui.control.EditField
        FilenameEditField_2Label        matlab.ui.control.Label
        SAVEACQUISITIONCheckBox_2       matlab.ui.control.CheckBox
        FolderPathZS                    matlab.ui.control.EditField
        FolderPathEditField_2Label      matlab.ui.control.Label
        SELECTFOLDERButton_3            matlab.ui.control.Button
        ImagingParametersPanel_2        matlab.ui.container.Panel
        PausetimesEditField             matlab.ui.control.NumericEditField
        PausetimesEditFieldLabel        matlab.ui.control.Label
        InitialpositionmmEditField      matlab.ui.control.NumericEditField
        InitialpositionmmEditFieldLabel  matlab.ui.control.Label
        NumberofstacksEditField         matlab.ui.control.NumericEditField
        NumberofstacksEditFieldLabel    matlab.ui.control.Label
        StepmmEditField_2               matlab.ui.control.NumericEditField
        StepmmEditField_2Label          matlab.ui.control.Label
        NumberofpixelsLabel_2           matlab.ui.control.Label
        forxEditField_2                 matlab.ui.control.NumericEditField
        forxEditField_2Label            matlab.ui.control.Label
        foryEditField_2                 matlab.ui.control.NumericEditField
        foryEditField_2Label            matlab.ui.control.Label
        CameraformatDropDown_2          matlab.ui.control.DropDown
        CameraformatDropDown_2Label     matlab.ui.control.Label
        NumberofspecklesEditField_2     matlab.ui.control.NumericEditField
        NumberofspecklesEditField_2Label  matlab.ui.control.Label
        STARTButton_2                   matlab.ui.control.Button
        AcquisitiontimeEditField_2      matlab.ui.control.NumericEditField
        AcquisitiontimeEditField_2Label  matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        info; %display Supported formats of camera
        vidformat; %choose video format (dynamic range, mode, binning, size)
        shutterOrca=0.100; %integration / exposure time,
        pixelsOrca= [1000 1000]; % # of pixels in each dimension
        bin=1; % camera binning (# of pixels)
        saveOn=1; % save displayed images % Description
        save_fold;
        nIm = 500; % # of images or speckles
        speckles;
        monitor = 2;
        SLMset;
        KDC101;
        timeout_val=60000
        RMMod; % Initialize the stack that will contain the images
        vidOrca;
        srcOrca;
        avg;
        stand;
        avg_std_save_fold;

        %Z-STACK
        zsvidformat;
        zsshutterOrca=0.100;
        zsnIm = 500;
        zspixelsOrca=[1000 1000];
        zssaveOn=1;
        zssave_fold;
        zsRMMod;
        zsnstack = 2;
        zsinpos;
        zsstep;
        zspausetime=10;
        avg2;
        stand2;
        zsavg_std_save_fold;


    end
    
    methods(Static)
       function KDC101 = Start_KDC101()

            NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.DeviceManagerCLI.dll');
            NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.GenericMotorCLI.dll');
            NET.addAssembly('C:\Program Files\Thorlabs\Kinesis\Thorlabs.MotionControl.KCube.DCServoCLI.dll');
            
            %Initialize Device List
            import Thorlabs.MotionControl.DeviceManagerCLI.*
            import Thorlabs.MotionControl.GenericMotorCLI.*
            import Thorlabs.MotionControl.KCube.DCServoCLI.*
            
            %Initialize Device List
            DeviceManagerCLI.BuildDeviceList();
            DeviceManagerCLI.GetDeviceListSize();
            SLnoList= Thorlabs.MotionControl.DeviceManagerCLI.DeviceManagerCLI.GetDeviceList();
            serialNumbers=cell(ToArray(SLnoList));
            
            %Should change the serial number below to the one being used.
            serial_num=serialNumbers{1};
            
            %Set up device and configuration
            KDC101 = KCubeDCServo.CreateKCubeDCServo(serial_num);
            KDC101.Connect(serial_num);
            KDC101.WaitForSettingsInitialized(5000);
            
            % configure the stage
            motorSettings = KDC101.LoadMotorConfiguration(serial_num);
            % motorSettings.DeviceSettingsName = 'PRM1/M-Z8';
            motorSettings.DeviceSettingsName = 'MTS50/M-Z8';
            
            % update the RealToDeviceUnit converter
            motorSettings.UpdateCurrentConfiguration();
            
            % push the settings down to the device
            MotorDeviceSettings = KDC101.MotorDeviceSettings;
            KDC101.SetSettings(MotorDeviceSettings, true, false);
            
            KDC101.StartPolling(250);
                        
       end
    end
    
    methods (Access = private)
       
        
        function [vid, src] = StartOrca_Assia(~,vidformat,shutter,pixels)
            %Establish communications with camera and optimize settings parameters
            % StartOrca7 modified by Assia Benachir September 2023
            
            vid = videoinput('hamamatsu', 1, vidformat); %create video object, using DefaultFormat: 'MONO8_2048x2048_FastMode'
            src = getselectedsource(vid); %read source info of camera
            % src.ReadoutMode = 'Area'; %check which is the default value and if it
            % changes with the selected vidformat
            
            %Determine acquisition parameters
            triggerconfig(vid, 'manual'); %pay attention, with Orca camera - 'manual' in lower case letter
            vid.TriggerRepeat = Inf; %set trigger repeat to infinite for unlimited # of images to record
            vid.FramesPerTrigger = 1; %# of frames saved in RAM per trigger call
            % src.TriggerSource = 'external'; %default value is 'internal'
            % src.TriggerMode = 'normal'; %default value is 'normal'
            % src.TriggerActive = 'edge';
            
            src.ExposureTime = shutter;
            %The following two lines are required to solve the problem of split images
            src.TriggerConnector = 'bnc';
            src.PixelType = 'mono16'; % default is mono8 (even though 16bit mode was selected) / error in coding from manufacturer
            % src.NumberofOutputTriggerConnector = 1;
            % src.NumberofView = 1;
            % src.OutputTriggerBaseOpt1 = 'view11';
            % src.OutputTriggerBaseOpt2 = 'view11';
            % src.OutputTriggerBaseOpt3 = 'view11';
            
            %SET region of interest (ROI)
            pxls = get(vid,'VideoResolution'); %read dimensions of video format object
            camx = pxls(1);%number of camera pixels in x-direction
            camy = pxls(2);%number of camera pixels in y-direction
            offsetx=0;offsety=offsetx;%the last value with respect to the [1920x1200] frame size position
            xpx=pixels(1); ypx=pixels(2);%number of camera pixels to aquire
            vid.ROIPosition = [round((camx/2 -xpx/2) -offsetx) round((camy/2 -ypx/2) -offsety) xpx ypx];%set ROI position and size [posX posY width height]
            %vid.ROIPosition = [0 576 2304 1152];
            
        end
        
        function [SLMset] = initSLMset(~, Monitor)
            if nargin < 1
                SLMset.Monitor = 1;
            else
                SLMset.Monitor = Monitor;
            end
            
            SLMset.Fig = figure(99);
            SLMset.Fig.ToolBar = 'none';
            SLMset.Fig.MenuBar = 'none';
            SLMset.pos = get(0, 'MonitorPositions');
            SLMset.Fig.Units = 'Pixels';
            SLMset.Fig.Position = SLMset.pos(SLMset.Monitor,:);
            % SLMset.Fig.Position = [1 1 800 600 ]
            SLMset.Fig.Colormap = gray(256);
            % SLMset.Pattern = 128*ones(fliplr(SLMset.Fig.Position(end-1:end )));
            %SLMset.Pattern = 128*ones(600,800);
            SLMset.Pattern = 128*ones(1024,1272); %adapted to our SLM dimensions -- why 128 though??
            SLMset.img = imagesc(uint8(SLMset.Pattern));
            SLMset.Axis = gca;
            SLMset.Axis.Units = 'Pixels';
            SLMset.Axis.Position = [1 1 1272 1024];
            SLMset.Axis.CLim = [0 255];
            SLMset.Axis.XTick = [];
            SLMset.Axis.YTick = [];
            SLMset.Axis.XTick = [];
            SLMset.Axis.YTick = [];
            SLMset.idInit = 1;
            SLMset.error = 0;
            % SLMset.img = imshow(uint8(SLMset.Pattern));
        end
        
        function [SLMset] = setSLM(~,SLM,SLMset)
            % SLMset.Fig = figure(99); SLMset.Fig.ToolBar = 'none'; SLMset.Fig.MenuBar
            % = 'none'; SLMset.pos = get(0, 'MonitorPositions'); SLMset.Fig.Position =
            % SLMset.pos(2,:); % SLMset.Fig.Position = [1 1 800 600 ]
            % SLMset.Fig.Colormap = gray(256); SLMset.Axis = gca; SLMset.Axis.Units =
            % 'Pixels'; SLMset.Axis.Position = [1 1 800 600]; SLMset.Axis.CLim = [0
            % 255]; SLMset.Axis.XTick = []; SLMset.Axis.YTick = []; SLMset.img =
            % image(uint8(SLM)); SLMset.Axis.XTick = []; SLMset.Axis.YTick = [];
            
            if isstruct(SLMset)
                if (isfield(SLMset,'Fig') && isfield(SLMset,'img'))
                    if SLMset.idInit== 1 && SLMset.error ==0
                        %% Draw now    
                    
                        SLMset.Pattern = SLM;
                        set(SLMset.img,'CData',uint8(SLM));
                        drawnow;
                        %pause(0.1); % settling time
                            
                        %% Setting Initialization and Error parameters
                        SLMset.error = 0;
                        SLMset.idInit = 1;
                    end
                end
                
            else
                disp('Error - SLMset is Corrupt')
            end             
        end    


        function savetiff(~,path, im)
            im = uint16(im);
            disp(['Writing stack to ' path ' ...'])
            imwrite(squeeze(im(:,:,1)),path,'tiff','Compression','none'); 
            for indz = 2:size(im,3)
                imwrite(squeeze(im(:,:,indz)),path,'tiff','WriteMode','append','Compression','none'); 
            end
            disp('... done!')
        end
        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import Thorlabs.MotionControl.DeviceManagerCLI.*
            import Thorlabs.MotionControl.GenericMotorCLI.*
            import Thorlabs.MotionControl.KCube.DCServoCLI.*
            app.RMMod=zeros(app.pixelsOrca(1),app.pixelsOrca(2),app.nIm,'uint16'); % Initialize the stack that will contain the images
            imaqreset
            app.info = imaqhwinfo('hamamatsu',1); %display Supported formats of camera
            app.vidformat = app.info.SupportedFormats{3}; %choose video format (dynamic range, mode, binning, size)
            app.zsvidformat = app.info.SupportedFormats{3};
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.LIVESwitch.Value = '0';
            app.STARTButton.Enable = false;
            app.LIVESwitch.Enable = false;
            app.RMMod=zeros(app.pixelsOrca(1),app.pixelsOrca(2),app.nIm,'uint16'); % Initialize the stack that will contain the images
            for tt=1:app.nIm
                app.SLMset = app.setSLM(app.speckles(:,:,tt),app.SLMset); %display pattern on SLM using Siddharth's script (faster)
                app.RMMod(:,:,tt) = getsnapshot(app.vidOrca); %get current image from memory
            end
            app.avg = sum(app.RMMod, 3); 
            imagesc(app.Average, app.avg) %Show the mean
            axis(app.Average,'equal');
            axis(app.Average,'tight');
            app.stand = std(double(app.RMMod),0,3);
            imagesc(app.StandardDev, app.stand) %Show the standard deviation
            axis(app.StandardDev,'equal');
            axis(app.StandardDev,'tight');
            if app.SAVEACQUISITIONCheckBox.Value == 1 %if we want to save this acquisition
                filename = app.FilenameEditField.Value;
                app.savetiff(fullfile(app.save_fold, [filename '.tif']), app.RMMod); %save stack as tiff
            end
            app.STARTButton.Enable = true;
            app.LIVESwitch.Enable = true;
        end

        % Value changed function: AcquisitiontimeEditField
        function AcquisitiontimeEditFieldValueChanged(app, event)
            value = app.AcquisitiontimeEditField.Value;
            app.shutterOrca = value;
        end

        % Value changed function: AcquisitiontimeEditField_2
        function AcquisitiontimeEditFieldZSValueChanged(app, event)
            value = app.AcquisitiontimeEditField_2.Value;
            app.zsshutterOrca = value;
        end

        % Button pushed function: SELECTFOLDERButton
        function SELECTFOLDERButtonPushed(app, event)
            app.save_fold = uigetdir;
            app.FolderPathEditField.Value = app.save_fold;
        end

        % Button pushed function: SELECTFOLDERButton_2
        function SELECTFOLDERButton_2Pushed(app, event)
            app.avg_std_save_fold = uigetdir;
            app.AverageandStandarddeviationFolderPathEditField.Value = app.avg_std_save_fold;
        end

        % Button pushed function: SELECTFOLDERButton_3
        function SELECTFOLDERButtonZSPushed(app, event)
            app.zssave_fold = uigetdir;
            app.FolderPathZS.Value = app.zssave_fold;
        end

        % Button pushed function: SELECTFOLDERButton_4
        function SELECTFOLDERButton_4Pushed(app, event)
            app.zsavg_std_save_fold = uigetdir;
            app.AveragesandStandardsdeviationsFolderPathEditField.Value = app.zsavg_std_save_fold;
        end

        % Value changed function: NumberofspecklesEditField
        function NumberofspecklesEditFieldValueChanged(app, event)
            value = app.NumberofspecklesEditField.Value;
            app.nIm = value;
        end

        % Value changed function: NumberofspecklesEditField_2
        function NumberofspecklesEditFieldZSValueChanged(app, event)
            value = app.NumberofspecklesEditField_2.Value;
            app.zsnIm = value;
        end

        % Value changed function: forxEditField
        function forxEditFieldValueChanged(app, event)
            value = app.forxEditField.Value;
            app.pixelsOrca(1) = value;
        end

        % Value changed function: forxEditField_2
        function forxEditFieldZSValueChanged(app, event)
            value = app.forxEditField_2.Value;
            app.zspixelsOrca(1) = value;
        end

        % Value changed function: foryEditField
        function foryEditFieldValueChanged(app, event)
            value = app.foryEditField.Value;
            app.zspixelsOrca(2) = value;
        end

        % Value changed function: foryEditField_2
        function foryEditFieldZSValueChanged(app, event)
            value = app.foryEditField_2.Value;
            app.pixelsOrca(2) = value;
        end

        % Value changed function: CameraformatDropDown
        function CameraformatDropDownValueChanged(app, event)
            value = app.CameraformatDropDown.Value;
            app.vidformat = app.info.SupportedFormats{str2double(value)};
        end

        % Value changed function: CameraformatDropDown_2
        function CameraformatDropDownZSValueChanged(app, event)
            value = app.CameraformatDropDown_2.Value;
            app.zsvidformat = app.info.SupportedFormats{str2double(value)};
        end

        % Value changed function: LIVESwitch
        function LIVESwitchValueChanged(app, event)
            value = app.LIVESwitch.Value;
            if value(1) == '1'
                h = figure('Name','LIVE MODE','NumberTitle','off'); h.Position=[0 0 2304 2304];
                %nr = 1;
                %nc = 1;
                %im=getsnapshot(app.vidOrca);
                %subplot(nr,nc,1); p1=imshow(im,[min(im(:)) max(im(:))],'InitialMagnification', 'fit'); colorbar; colormap(pink); set(gca,'FontSize',16); axis image; axis on; xticks(0:app.pixelsOrca/4:app.pixelsOrca); yticks(0:app.pixelsOrca/4:app.pixelsOrca); % For Linear signal (adaptable scale)
                while value(1) == '1' && ishandle(h)
                    value = app.LIVESwitch.Value;
                    im=getsnapshot(app.vidOrca); %get current image from memory
                    if app.bin>1; im=imresize(im,1/app.bin,'bilinear'); end %binning applied on software level
                    IMax = max(im(:));
                    %subplot(nr,nc,1);                   
                    imagesc(im); %scalebar updates
                    axis image; colorbar; grid on; set(gca,'GridColor',[1 1 1],'color',[0 0 0]);
                    [r,c,~]=find(max(im(:))==im); title({sprintf('I_m_a_x (%.f)',IMax),sprintf('I_m_a_x at (%.f,%.f)',r(1),c(1))});
                    drawnow limitrate
                end
            end
        end

        % Button pushed function: ConnectButtonCam, ConnectButtonCam_2
        function ConnectButtonCamPushed(app, event)
            if ~exist('app.vidOrca','var') 
                [app.vidOrca, app.srcOrca] = StartOrca_Assia(app,app.vidformat,app.shutterOrca,app.pixelsOrca);
            elseif exist('app.vidOrca','var')
                ROI=app.vidOrca.ROIPosition;
                if ROI(3) ~= app.pixelsOrca(1) || app.srcOrca.ExposureTime ~= app.shutterOrca
                    stop(app.vidOrca);
                    [app.vidOrca, app.srcOrca] = StartOrca_Assia(app,app.vidformat,app.shutterOrca,app.pixelsOrca);
                end
            end
            
            if ~isrunning(app.vidOrca); flushdata(app.vidOrca);start(app.vidOrca); end % start acquiring images
            app.srcOrca.ExposureTime=app.shutterOrca; %CHECK THAT THIS VALUE DOESN'T GET OVERWRITTEN
            propinfo(app.srcOrca,'BitsPerChannel');
            app.CameraLamp.Color = 'green';
            app.CameraLamp_2.Color = 'green';
            
        end

        % Button pushed function: DisconnectButtonCam, 
        % ...and 1 other component
        function DisconnectButtonCamPushed(app, event)
            app.CameraLamp.Color = 'red';
            app.CameraLamp_2.Color = 'red';
            stop(imaqfind);
            delete(imaqfind);
            clear vidOrca srcOrca
        end

        % Button pushed function: ConnectButtonSLM, ConnectButtonSLM_2
        function ConnectButtonSLMPushed(app, event)
            app.speckles = load('speckle.mat');
            app.speckles = app.speckles.speckles;
            %imagesc(app.UIAxes, app.speckles(:,:,1999));
            %axis(app.UIAxes,'equal');
            %axis(app.UIAxes,'tight');
            app.SLMset = app.initSLMset(app.monitor); %initialise the SLM display
            app.SLMset = app.setSLM(app.speckles(:,:,1999),app.SLMset); %display the first speckle
            app.SLMLamp.Color = 'green';
            app.SLMLamp_2.Color = 'green';
            app.ConnectButtonSLM.Enable = false;
            app.ConnectButtonSLM_2.Enable = false;
        end

        % Button pushed function: ConnectButtonMotor, ConnectButtonMotor_2
        function ConnectButtonMotorPushed(app, event)
            app.KDC101 = app.Start_KDC101();
            app.MotorstageLamp.Color = 'green';
            app.MotorstageLamp_2.Color = 'green';
            app.ConnectButtonMotor.Enable = false;
            app.ConnectButtonMotor_2.Enable = false;
        end

        % Button pushed function: GetpositionMotorstageButton
        function GetpositionMotorstageButtonPushed(app, event)
            app.ResultPosition.Value = System.Decimal.ToDouble(app.KDC101.Position);
        end

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
            POS = System.Decimal.ToDouble(app.KDC101.Position);
            app.KDC101.MoveTo(POS + app.StepmmEditField.Value, app.timeout_val)
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
            POS = System.Decimal.ToDouble(app.KDC101.Position);
            app.KDC101.MoveTo(POS - app.StepmmEditField.Value, app.timeout_val)
        end

        % Button pushed function: SAVETHEIMAGESButton
        function SAVETHEIMAGESButtonPushed(app, event)
            filenameavg = app.FilenameAverageEditField.Value;
            filenamestd = app.FilenameStandarddeviationEditField.Value;
            pathavg = fullfile(app.zsavg_std_save_fold, [filenameavg '.tif']);
            pathstd = fullfile(app.zsavg_std_save_fold, [filenamestd '.tif']);
            imwrite((app.avg)/max(app.avg,[],"all"), pathavg); %Without the max division, full white image
            imwrite((app.stand)/max(app.stand,[],"all"), pathstd);
            disp('Done')
        end

        % Value changed function: NumberofstacksEditField
        function NumberofstacksEditFieldValueChanged(app, event)
            value = app.NumberofstacksEditField.Value;
            app.zsnstack = value;
        end

        % Value changed function: InitialpositionmmEditField
        function InitialpositionmmEditFieldValueChanged(app, event)
            value = app.InitialpositionmmEditField.Value;
            app.zsinpos = value;
        end

        % Value changed function: StepmmEditField_2
        function StepmmEditField_2ValueChanged(app, event)
            value = app.StepmmEditField_2.Value;
            app.zsstep = value;
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            app.LIVESwitch.Value = '0';
            app.LIVESwitch.Enable = false;
            app.STARTButton_2.Enable = false;
            app.zsRMMod=zeros(app.zspixelsOrca(1),app.zspixelsOrca(2),app.zsnIm,'uint16'); % Initialize the stack that will contain the images
           
            app.KDC101.MoveTo(app.zsinpos, app.timeout_val);
            pause(app.zspausetime) % Waiting for the stage to move
            pos = System.Decimal.ToDouble(app.KDC101.Position);
            tic
            for k = 1:app.zsnstack
                for tt=1:app.zsnIm
                    app.SLMset = app.setSLM(app.speckles(:,:,tt),app.SLMset); %display pattern on SLM using Siddharth's script (faster)
                    app.zsRMMod(:,:,tt) = getsnapshot(app.vidOrca); %get current image from memory 
                end
                if app.SAVEACQUISITIONCheckBox_2.Value == 1
                filenameinput = app.FilenameEditField_2.Value;
                filename = [num2str(tt,'%.3d') '_' filenameinput num2str(k,'%.d')];
                app.savetiff(fullfile(app.zssave_fold, [filename '.tif']), app.zsRMMod); %save stack as tiff
                end
                app.avg2 = sum(app.zsRMMod, 3); 
                imagesc(app.Average_2, app.avg2) %Show the mean
                axis(app.Average_2,'equal');
                axis(app.Average_2,'tight');
                app.stand2 = std(double(app.zsRMMod),0,3);
                imagesc(app.StandardDev_2, app.stand2) %Show the standard deviation
                axis(app.StandardDev_2,'equal');
                axis(app.StandardDev_2,'tight');
                if app.SAVEAVERAGESANDSTANDARDSDEVIATIONSCheckBox.Value == 1
                    filenameavg = [app.FilenameAverageEditField_2.Value num2str(k,'%.d')];
                    filenamestd = [app.FilenameStandarddeviationEditField_2.Value num2str(k,'%.d')];
                    pathavg = fullfile(app.avg_std_save_fold, [filenameavg '.tif']);
                    pathstd = fullfile(app.avg_std_save_fold, [filenamestd '.tif']);
                    imwrite((app.avg2)/max(app.avg2,[],"all"), pathavg); %Without the max division, full white image
                    imwrite((app.stand2)/max(app.stand2,[],"all"), pathstd);
                    disp('Done')
                end
                toc
                app.KDC101.MoveTo(pos+app.zsstep, app.timeout_val);
                pause(app.zspausetime) % Waiting for the stage to move
                pos = System.Decimal.ToDouble(app.KDC101.Position);
            end
            app.STARTButton_2.Enable = true;
            app.LIVESwitch.Enable = true;
        end

        % Value changed function: PausetimesEditField
        function PausetimesEditFieldValueChanged(app, event)
            value = app.PausetimesEditField.Value;
            app.zspausetime = value;    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicTab = uitab(app.TabGroup);
            app.BasicTab.Title = 'Basic';
            app.GridLayout = uigridlayout(app.BasicTab);
            app.ImagingParametersPanel = uipanel(app.GridLayout);
            app.ImagingParametersPanel.Title = 'Imaging Parameters';
            app.AcquisitiontimeEditFieldLabel = uilabel(app.ImagingParametersPanel);
            app.AcquisitiontimeEditFieldLabel.Text = 'Acquisition time';
            app.AcquisitiontimeEditField = uieditfield(app.ImagingParametersPanel, 'numeric');
            app.AcquisitiontimeEditField.ValueChangedFcn = createCallbackFcn(app, @AcquisitiontimeEditFieldValueChanged, true);
            app.AcquisitiontimeEditField.Value = 0.1;
            app.STARTButton = uibutton(app.ImagingParametersPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.NumberofspecklesEditFieldLabel = uilabel(app.ImagingParametersPanel);
            app.NumberofspecklesEditFieldLabel.Text = 'Number of speckles';
            app.NumberofspecklesEditField = uieditfield(app.ImagingParametersPanel, 'numeric');
            app.NumberofspecklesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofspecklesEditFieldValueChanged, true);
            app.NumberofspecklesEditField.Value = 500;
            app.CameraformatDropDownLabel = uilabel(app.ImagingParametersPanel);
            app.CameraformatDropDownLabel.Text = 'Camera format';
            app.CameraformatDropDown = uidropdown(app.ImagingParametersPanel);
            app.CameraformatDropDown.Items = {'MONO16_2304x2304_FastMode', 'MONO16_2304x2304_FasterMode', 'MONO16_2304x2304_SlowMode', 'MONO16_BIN2x2_1152x1152_FastMode', 'MONO16_BIN2x2_1152x1152_FasterMode', 'MONO16_BIN2x2_1152x1152_SlowMode', 'MONO16_BIN4x4_576x576_FastMode', 'MONO16_BIN4x4_576x576_FasterMode', 'MONO16_BIN4x4_576x576_SlowMode', 'MONO16_LightSheet Mode_FastMode', 'MONO16_LightSheet Mode_FasterMode', 'MONO16_LightSheet Mode_SlowMode', 'MONO8_2304x2304_FastMode', 'MONO8_2304x2304_FasterMode', 'MONO8_2304x2304_SlowMode', 'MONO8_BIN2x2_1152x1152_FastMode', 'MONO8_BIN2x2_1152x1152_FasterMode', 'MONO8_BIN2x2_1152x1152_SlowMode', 'MONO8_BIN4x4_576x576_FastMode', 'MONO8_BIN4x4_576x576_FasterMode', 'MONO8_BIN4x4_576x576_SlowMode', 'MONO8_LightSheet Mode_FastMode', 'MONO8_LightSheet Mode_FasterMode', 'MONO8_LightSheet Mode_SlowMode'};
            app.CameraformatDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.CameraformatDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraformatDropDownValueChanged, true);
            app.CameraformatDropDown.Value = '3';
            app.foryEditFieldLabel = uilabel(app.ImagingParametersPanel);
            app.foryEditFieldLabel.Text = 'for y';
            app.foryEditField = uieditfield(app.ImagingParametersPanel, 'numeric');
            app.foryEditField.ValueChangedFcn = createCallbackFcn(app, @foryEditFieldValueChanged, true);
            app.foryEditField.Value = 1000;
            app.forxEditFieldLabel = uilabel(app.ImagingParametersPanel);
            app.forxEditFieldLabel.Text = 'for x';
            app.forxEditField = uieditfield(app.ImagingParametersPanel, 'numeric');
            app.forxEditField.ValueChangedFcn = createCallbackFcn(app, @forxEditFieldValueChanged, true);
            app.forxEditField.Value = 1000;
            app.NumberofpixelsLabel = uilabel(app.ImagingParametersPanel);
            app.NumberofpixelsLabel.Text = 'Number of pixels ';
            app.UPButton = uibutton(app.ImagingParametersPanel, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Text = 'UP';
            app.DOWNButton = uibutton(app.ImagingParametersPanel, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Text = 'DOWN';
            app.MovethezstagepositionLabel = uilabel(app.ImagingParametersPanel);
            app.MovethezstagepositionLabel.Text = 'Move the z stage position';
            app.GetpositionMotorstageButton = uibutton(app.ImagingParametersPanel, 'push');
            app.GetpositionMotorstageButton.ButtonPushedFcn = createCallbackFcn(app, @GetpositionMotorstageButtonPushed, true);
            app.GetpositionMotorstageButton.Text = 'Get position Motor stage';
            app.ResultPosition = uieditfield(app.ImagingParametersPanel, 'numeric');
            app.StepmmEditFieldLabel = uilabel(app.ImagingParametersPanel);
            app.StepmmEditFieldLabel.Text = 'Step (mm)';
            app.StepmmEditField = uieditfield(app.ImagingParametersPanel, 'numeric');
            app.StepmmEditField.Limits = [0.0001 10];
            app.StepmmEditField.Value = 0.0001;
            app.SavingPanel = uipanel(app.GridLayout);
            app.SavingPanel.Title = 'Saving';
            app.SELECTFOLDERButton = uibutton(app.SavingPanel, 'push');
            app.SELECTFOLDERButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTFOLDERButtonPushed, true);
            app.SELECTFOLDERButton.Text = 'SELECT FOLDER';
            app.FolderPathEditFieldLabel = uilabel(app.SavingPanel);
            app.FolderPathEditFieldLabel.Text = 'Folder Path';
            app.FolderPathEditField = uieditfield(app.SavingPanel, 'text');
            app.SAVEACQUISITIONCheckBox = uicheckbox(app.SavingPanel);
            app.SAVEACQUISITIONCheckBox.Text = 'SAVE ACQUISITION';
            app.SAVEACQUISITIONCheckBox.Value = true;
            app.FilenameEditFieldLabel = uilabel(app.SavingPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.SavingPanel, 'text');
            app.ConnectionsPanel = uipanel(app.GridLayout);
            app.ConnectionsPanel.Title = 'Connections';
            app.CameraLampLabel = uilabel(app.ConnectionsPanel);
            app.CameraLampLabel.Text = 'Camera';
            app.CameraLamp = uilamp(app.ConnectionsPanel);
            app.SLMLampLabel = uilabel(app.ConnectionsPanel);
            app.SLMLampLabel.Text = 'SLM';
            app.SLMLamp = uilamp(app.ConnectionsPanel);
            app.MotorstageLampLabel = uilabel(app.ConnectionsPanel);
            app.MotorstageLampLabel.Text = 'Motor stage';
            app.MotorstageLamp = uilamp(app.ConnectionsPanel);
            app.ConnectButtonCam = uibutton(app.ConnectionsPanel, 'push');
            app.ConnectButtonCam.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonCamPushed, true);
            app.ConnectButtonCam.Text = 'Connect';
            app.DisconnectButtonCam = uibutton(app.ConnectionsPanel, 'push');
            app.DisconnectButtonCam.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonCamPushed, true);
            app.DisconnectButtonCam.Text = 'Disconnect';
            app.ConnectButtonSLM = uibutton(app.ConnectionsPanel, 'push');
            app.ConnectButtonSLM.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonSLMPushed, true);
            app.ConnectButtonSLM.Text = 'Connect';
            app.ConnectButtonMotor = uibutton(app.ConnectionsPanel, 'push');
            app.ConnectButtonMotor.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonMotorPushed, true);
            app.ConnectButtonMotor.Text = 'Connect';
            app.ImagesPanel = uipanel(app.GridLayout);
            app.ImagesPanel.Title = 'Images';
            app.Average = uiaxes(app.ImagesPanel);
            title(app.Average, 'Average')
            xlabel(app.Average, 'X pixels')
            ylabel(app.Average, 'Y pixels')
            zlabel(app.Average, 'Z')
            app.StandardDev = uiaxes(app.ImagesPanel);
            title(app.StandardDev, 'Standard deviation')
            xlabel(app.StandardDev, 'X pixels')
            ylabel(app.StandardDev, 'Y pixels')
            zlabel(app.StandardDev, 'Z')
            app.LIVESwitchLabel = uilabel(app.ImagesPanel);
            app.LIVESwitchLabel.Text = 'LIVE';
            app.LIVESwitch = uiswitch(app.ImagesPanel, 'slider');
            app.LIVESwitch.ItemsData = {'0', '1'};
            app.LIVESwitch.ValueChangedFcn = createCallbackFcn(app, @LIVESwitchValueChanged, true);
            app.LIVESwitch.Value = '0';
            app.SAVETHEIMAGESButton = uibutton(app.ImagesPanel, 'push');
            app.SAVETHEIMAGESButton.ButtonPushedFcn = createCallbackFcn(app, @SAVETHEIMAGESButtonPushed, true);
            app.SAVETHEIMAGESButton.Text = 'SAVE THE IMAGES';
            app.FilenameAverageLabel = uilabel(app.ImagesPanel);
            app.FilenameAverageLabel.Text = 'Filename Average :';
            app.FilenameAverageEditField = uieditfield(app.ImagesPanel, 'text');
            app.FilenameStandarddeviationLabel = uilabel(app.ImagesPanel);
            app.FilenameStandarddeviationLabel.Text = 'Filename Standard deviation :';
            app.FilenameStandarddeviationEditField = uieditfield(app.ImagesPanel, 'text');
            app.SELECTFOLDERButton_2 = uibutton(app.ImagesPanel, 'push');
            app.SELECTFOLDERButton_2.ButtonPushedFcn = createCallbackFcn(app, @SELECTFOLDERButton_2Pushed, true);
            app.SELECTFOLDERButton_2.Text = 'SELECT FOLDER';
            app.AverageandStandarddeviationFolderPathEditFieldLabel = uilabel(app.ImagesPanel);
            app.AverageandStandarddeviationFolderPathEditFieldLabel.Text = 'Average and Standard deviation Folder Path';
            app.AverageandStandarddeviationFolderPathEditField = uieditfield(app.ImagesPanel, 'text');
            app.ZSTACKTab = uitab(app.TabGroup);
            app.ZSTACKTab.Title = 'Z-STACK';
            app.GridLayout2 = uigridlayout(app.ZSTACKTab);
            app.ImagingParametersPanel_2 = uipanel(app.ZSTACKTab);
            app.ImagingParametersPanel_2.Title = 'Imaging Parameters';
            app.AcquisitiontimeEditField_2Label = uilabel(app.ImagingParametersPanel_2);
            app.AcquisitiontimeEditField_2Label.Text = 'Acquisition time';
            app.AcquisitiontimeEditField_2 = uieditfield(app.ImagingParametersPanel_2, 'numeric');
            app.AcquisitiontimeEditField_2.ValueChangedFcn = createCallbackFcn(app, @AcquisitiontimeEditFieldZSValueChanged, true);
            app.AcquisitiontimeEditField_2.Value = 0.1;
            app.STARTButton_2 = uibutton(app.ImagingParametersPanel_2, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.NumberofspecklesEditField_2Label = uilabel(app.ImagingParametersPanel_2);
            app.NumberofspecklesEditField_2Label.Text = 'Number of speckles';
            app.NumberofspecklesEditField_2 = uieditfield(app.ImagingParametersPanel_2, 'numeric');
            app.NumberofspecklesEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofspecklesEditFieldZSValueChanged, true);
            app.NumberofspecklesEditField_2.Value = 500;
            app.CameraformatDropDown_2Label = uilabel(app.ImagingParametersPanel_2);
            app.CameraformatDropDown_2Label.Text = 'Camera format';
            app.CameraformatDropDown_2 = uidropdown(app.ImagingParametersPanel_2);
            app.CameraformatDropDown_2.Items = {'MONO16_2304x2304_FastMode', 'MONO16_2304x2304_FasterMode', 'MONO16_2304x2304_SlowMode', 'MONO16_BIN2x2_1152x1152_FastMode', 'MONO16_BIN2x2_1152x1152_FasterMode', 'MONO16_BIN2x2_1152x1152_SlowMode', 'MONO16_BIN4x4_576x576_FastMode', 'MONO16_BIN4x4_576x576_FasterMode', 'MONO16_BIN4x4_576x576_SlowMode', 'MONO16_LightSheet Mode_FastMode', 'MONO16_LightSheet Mode_FasterMode', 'MONO16_LightSheet Mode_SlowMode', 'MONO8_2304x2304_FastMode', 'MONO8_2304x2304_FasterMode', 'MONO8_2304x2304_SlowMode', 'MONO8_BIN2x2_1152x1152_FastMode', 'MONO8_BIN2x2_1152x1152_FasterMode', 'MONO8_BIN2x2_1152x1152_SlowMode', 'MONO8_BIN4x4_576x576_FastMode', 'MONO8_BIN4x4_576x576_FasterMode', 'MONO8_BIN4x4_576x576_SlowMode', 'MONO8_LightSheet Mode_FastMode', 'MONO8_LightSheet Mode_FasterMode', 'MONO8_LightSheet Mode_SlowMode'};
            app.CameraformatDropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.CameraformatDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CameraformatDropDownZSValueChanged, true);
            app.CameraformatDropDown_2.Value = '3';
            app.foryEditField_2Label = uilabel(app.ImagingParametersPanel_2);
            app.foryEditField_2Label.Text = 'for y';
            app.foryEditField_2 = uieditfield(app.ImagingParametersPanel_2, 'numeric');
            app.foryEditField_2.ValueChangedFcn = createCallbackFcn(app, @foryEditFieldZSValueChanged, true);
            app.foryEditField_2.Value = 1000;
            app.forxEditField_2Label = uilabel(app.ImagingParametersPanel_2);
            app.forxEditField_2Label.Text = 'for x';
            app.forxEditField_2 = uieditfield(app.ImagingParametersPanel_2, 'numeric');
            app.forxEditField_2.ValueChangedFcn = createCallbackFcn(app, @forxEditFieldZSValueChanged, true);
            app.forxEditField_2.Value = 1000;
            app.NumberofpixelsLabel_2 = uilabel(app.ImagingParametersPanel_2);
            app.NumberofpixelsLabel_2.Text = 'Number of pixels ';
            app.StepmmEditField_2Label = uilabel(app.ImagingParametersPanel_2);
            app.StepmmEditField_2Label.Text = 'Step (mm)';
            app.StepmmEditField_2 = uieditfield(app.ImagingParametersPanel_2, 'numeric');
            app.StepmmEditField_2.Limits = [0.0001 10];
            app.StepmmEditField_2.ValueChangedFcn = createCallbackFcn(app, @StepmmEditField_2ValueChanged, true);
            app.StepmmEditField_2.Value = 0.0001;
            app.NumberofstacksEditFieldLabel = uilabel(app.ImagingParametersPanel_2);
            app.NumberofstacksEditFieldLabel.Text = 'Number of stacks';
            app.NumberofstacksEditField = uieditfield(app.ImagingParametersPanel_2, 'numeric');
            app.NumberofstacksEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofstacksEditFieldValueChanged, true);
            app.NumberofstacksEditField.Value = 2;
            app.InitialpositionmmEditFieldLabel = uilabel(app.ImagingParametersPanel_2);
            app.InitialpositionmmEditFieldLabel.Text = 'Initial position (mm)';
            app.InitialpositionmmEditField = uieditfield(app.ImagingParametersPanel_2, 'numeric');
            app.InitialpositionmmEditField.ValueChangedFcn = createCallbackFcn(app, @InitialpositionmmEditFieldValueChanged, true);
            app.PausetimesEditFieldLabel = uilabel(app.ImagingParametersPanel_2);
            app.PausetimesEditFieldLabel.Text = 'Pause time (s)';
            app.PausetimesEditField = uieditfield(app.ImagingParametersPanel_2, 'numeric');
            app.PausetimesEditField.ValueChangedFcn = createCallbackFcn(app, @PausetimesEditFieldValueChanged, true);
            app.PausetimesEditField.Value = 5;
            app.SavingPanel_2 = uipanel(app.ZSTACKTab);
            app.SavingPanel_2.Title = 'Saving';
            app.SELECTFOLDERButton_3 = uibutton(app.SavingPanel_2, 'push');
            app.SELECTFOLDERButton_3.ButtonPushedFcn = createCallbackFcn(app, @SELECTFOLDERButtonZSPushed, true);
            app.SELECTFOLDERButton_3.Text = 'SELECT FOLDER';
            app.FolderPathEditField_2Label = uilabel(app.SavingPanel_2);
            app.FolderPathEditField_2Label.Text = 'Folder Path';
            app.FolderPathZS = uieditfield(app.SavingPanel_2, 'text');
            app.SAVEACQUISITIONCheckBox_2 = uicheckbox(app.SavingPanel_2);
            app.SAVEACQUISITIONCheckBox_2.Text = 'SAVE ACQUISITION';
            app.SAVEACQUISITIONCheckBox_2.Value = true;
            app.FilenameEditField_2Label = uilabel(app.SavingPanel_2);
            app.FilenameEditField_2Label.Text = 'Filename';
            app.FilenameEditField_2 = uieditfield(app.SavingPanel_2, 'text');
            app.ConnectionsPanel_2 = uipanel(app.ZSTACKTab);
            app.ConnectionsPanel_2.Title = 'Connections';
            app.CameraLamp_2Label = uilabel(app.ConnectionsPanel_2);
            app.CameraLamp_2Label.Text = 'Camera';
            app.CameraLamp_2 = uilamp(app.ConnectionsPanel_2);
            app.SLMLamp_2Label = uilabel(app.ConnectionsPanel_2);
            app.SLMLamp_2Label.Text = 'SLM';
            app.SLMLamp_2 = uilamp(app.ConnectionsPanel_2);
            app.MotorstageLamp_2Label = uilabel(app.ConnectionsPanel_2);
            app.MotorstageLamp_2Label.Text = 'Motor stage';
            app.MotorstageLamp_2 = uilamp(app.ConnectionsPanel_2);
            app.ConnectButtonCam_2 = uibutton(app.ConnectionsPanel_2, 'push');
            app.ConnectButtonCam_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonCamPushed, true);
            app.ConnectButtonCam_2.Text = 'Connect';
            app.DisconnectButtonCam_2 = uibutton(app.ConnectionsPanel_2, 'push');
            app.DisconnectButtonCam_2.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonCamPushed, true);
            app.DisconnectButtonCam_2.Text = 'Disconnect';
            app.ConnectButtonSLM_2 = uibutton(app.ConnectionsPanel_2, 'push');
            app.ConnectButtonSLM_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonSLMPushed, true);
            app.ConnectButtonSLM_2.Text = 'Connect';
            app.ConnectButtonMotor_2 = uibutton(app.ConnectionsPanel_2, 'push');
            app.ConnectButtonMotor_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonMotorPushed, true);
            app.ConnectButtonMotor_2.Text = 'Connect';
            app.ImagesPanel_2 = uipanel(app.ZSTACKTab);
            app.ImagesPanel_2.Title = 'Images';
            app.Average_2 = uiaxes(app.ImagesPanel_2);
            title(app.Average_2, 'Average')
            xlabel(app.Average_2, 'X pixels')
            ylabel(app.Average_2, 'Y pixels')
            zlabel(app.Average_2, 'Z')
            app.StandardDev_2 = uiaxes(app.ImagesPanel_2);
            title(app.StandardDev_2, 'Standard deviation')
            xlabel(app.StandardDev_2, 'X pixels')
            ylabel(app.StandardDev_2, 'Y pixels')
            zlabel(app.StandardDev_2, 'Z')
            app.FilenameAverageEditField_2Label = uilabel(app.ImagesPanel_2);
            app.FilenameAverageEditField_2Label.Text = 'Filename Average :';
            app.FilenameAverageEditField_2 = uieditfield(app.ImagesPanel_2, 'text');
            app.FilenameStandarddeviationEditField_2Label = uilabel(app.ImagesPanel_2);
            app.FilenameStandarddeviationEditField_2Label.Text = 'Filename Standard deviation :';
            app.FilenameStandarddeviationEditField_2 = uieditfield(app.ImagesPanel_2, 'text');
            app.SELECTFOLDERButton_4 = uibutton(app.ImagesPanel_2, 'push');
            app.SELECTFOLDERButton_4.ButtonPushedFcn = createCallbackFcn(app, @SELECTFOLDERButton_4Pushed, true);
            app.SELECTFOLDERButton_4.Text = 'SELECT FOLDER';
            app.AveragesandStandardsdeviationsFolderPathEditFieldLabel = uilabel(app.ImagesPanel_2);
            app.AveragesandStandardsdeviationsFolderPathEditFieldLabel.Text = 'Averages and Standards deviations Folder Path';
            app.AveragesandStandardsdeviationsFolderPathEditField = uieditfield(app.ImagesPanel_2, 'text');
            app.SAVEAVERAGESANDSTANDARDSDEVIATIONSCheckBox = uicheckbox(app.ImagesPanel_2);
            app.SAVEAVERAGESANDSTANDARDSDEVIATIONSCheckBox.Text = 'SAVE AVERAGES AND STANDARDS DEVIATIONS';
            app.SAVEAVERAGESANDSTANDARDSDEVIATIONSCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface_manip_matlab2018

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end